classdef wahpedalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SelectAudioDropDownLabel      matlab.ui.control.Label
        SelectAudioDropDown           matlab.ui.control.DropDown
        CenterFrequencyHzSliderLabel  matlab.ui.control.Label
        CenterFrequencyHzSlider       matlab.ui.control.Slider
        QRpKnobLabel                  matlab.ui.control.Label
        QRpKnob                       matlab.ui.control.Knob
        GainKnobLabel                 matlab.ui.control.Label
        GainKnob                      matlab.ui.control.Knob
        RightPanel                    matlab.ui.container.Panel
        PlayButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        fs;
    end
    
    methods (Access = private)
        
        function updatewah(app)
            
            y = wahwah(app);
            y = fftshift(fft(y));
            
            t = (0:length(y)-1)/app.fs;
            f = -app.fs/2:app.fs/length(t):app.fs/2-app.fs/length(t);
            
            plot(app.UIAxes, f, abs(y));
            
            sound(wahwah(app), app.fs);
            
        end
        
        
        function y = wahwah(app)
            
            fc = app.CenterFrequencyHzSlider.Value;
            Rp = app.QRpKnob.Value;
            gain = app.GainKnob.Value;
            [x, app.fs] = audioread(app.SelectAudioDropDown.Value);
            
            % make mono
            x = x(:, 1);
            
            % avoid inconsistent dims of arrays
            x = x';
            
            % get analog freq in radians
            wT = 2*pi*fc*1/app.fs;
            
            % define b and a for a standard wah
            b = [1 0 -1];
            a = [1 -2*Rp*cos(wT) Rp^2];
            
            y = filter(b, a, x*gain);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SelectAudioDropDown.Items = {'GtrRingBitCrush.aif', 'ScreamySynth.wav'};
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            updatewah(app);
        end

        % Value changed function: CenterFrequencyHzSlider
        function CenterFrequencyHzSliderValueChanged(app, event)
            updatewah(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {305, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectAudioDropDownLabel = uilabel(app.LeftPanel);
            app.SelectAudioDropDownLabel.Text = 'Select Audio';
            app.SelectAudioDropDown = uidropdown(app.LeftPanel);
            app.CenterFrequencyHzSliderLabel = uilabel(app.LeftPanel);
            app.CenterFrequencyHzSliderLabel.Text = 'Center Frequency (Hz)';
            app.CenterFrequencyHzSlider = uislider(app.LeftPanel);
            app.CenterFrequencyHzSlider.Limits = [300 2500];
            app.CenterFrequencyHzSlider.MajorTicks = [300 500 1000 1500 2000 2500];
            app.CenterFrequencyHzSlider.MajorTickLabels = {'300', '500', '1000', '1500', '2000', '2500'};
            app.CenterFrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyHzSliderValueChanged, true);
            app.CenterFrequencyHzSlider.Value = 300;
            app.QRpKnobLabel = uilabel(app.LeftPanel);
            app.QRpKnobLabel.Text = 'Q (Rp)';
            app.QRpKnob = uiknob(app.LeftPanel, 'continuous');
            app.QRpKnob.Limits = [0 1];
            app.QRpKnob.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.GainKnobLabel = uilabel(app.LeftPanel);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.LeftPanel, 'continuous');
            app.GainKnob.Limits = [0 1];
            app.GainKnob.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.RightPanel = uipanel(app.GridLayout);
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Frequency Response')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wahpedalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end